classdef AcqNalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AcqNalysisUIFigure           matlab.ui.Figure
        FilesList                    matlab.ui.control.ListBox
        FilesPanel                   matlab.ui.container.Panel
        HelpButton                   matlab.ui.control.Button
        IDsessionparadigmLabel       matlab.ui.control.Label
        PatternEditField             matlab.ui.control.EditField
        PatternLabel                 matlab.ui.control.Label
        ShowParadigmsButton          matlab.ui.control.Button
        ShowAssignationsButton       matlab.ui.control.Button
        ParadigmsButton              matlab.ui.control.Button
        GroupAssignationButton       matlab.ui.control.Button
        ParadigmsFilesLabel          matlab.ui.control.TextArea
        AssignationFileLabel         matlab.ui.control.TextArea
        FolderLabel                  matlab.ui.control.TextArea
        DataFolderButton             matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        AnalysisTab                  matlab.ui.container.Tab
        DenoisingButtonGroup         matlab.ui.container.ButtonGroup
        WaveletsButton               matlab.ui.control.RadioButton
        PCAButton                    matlab.ui.control.RadioButton
        BandpassfilterPanel          matlab.ui.container.Panel
        HighfreqHzEditField          matlab.ui.control.NumericEditField
        HighfreqHzEditFieldLabel     matlab.ui.control.Label
        LowfreqHzEditField           matlab.ui.control.NumericEditField
        LowfreqHzEditFieldLabel      matlab.ui.control.Label
        AttenuationdBEditField       matlab.ui.control.NumericEditField
        AttenuationdBEditFieldLabel  matlab.ui.control.Label
        SteepnessEditField           matlab.ui.control.NumericEditField
        SteepnessEditFieldLabel      matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        CutoffEditField              matlab.ui.control.NumericEditField
        CutoffEditFieldLabel         matlab.ui.control.Label
        WinsizeEditField             matlab.ui.control.NumericEditField
        WinsizeEditFieldLabel        matlab.ui.control.Label
        ThreshEditField              matlab.ui.control.NumericEditField
        ThreshEditFieldLabel         matlab.ui.control.Label
        WaveletsLabel                matlab.ui.control.Label
        PCALabel                     matlab.ui.control.Label
        PeristimulusresponsePanel    matlab.ui.container.Panel
        ResponseEndEditField         matlab.ui.control.NumericEditField
        BaselineEndEditField         matlab.ui.control.NumericEditField
        ResponseStartEditField       matlab.ui.control.NumericEditField
        ResponseIntervalsEditFieldLabel  matlab.ui.control.Label
        ReferenceDropDown            matlab.ui.control.DropDown
        ReferenceDropDownLabel       matlab.ui.control.Label
        BaselineStartEditField       matlab.ui.control.NumericEditField
        BaselineIntervalsEditFieldLabel  matlab.ui.control.Label
        RunselectedButton            matlab.ui.control.Button
        CloseallButton               matlab.ui.control.Button
        ChannelsUITable              matlab.ui.control.Table
        RunallButton                 matlab.ui.control.Button
        DisplayButton                matlab.ui.control.Button
        DisplaydataTab               matlab.ui.container.Tab
        ResetButton                  matlab.ui.control.Button
        MoveButton                   matlab.ui.control.StateButton
        ZoomButton                   matlab.ui.control.StateButton
        DispChanListBox              matlab.ui.control.ListBox
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        folderpath;
        filepath;
        filesindir;
        assignations;
        paradigms;
        Node2;
        data;
        selectedfile;
        fs;
        sqrt_CR;
        namepattern;
        fileinfo;
        stimorder;
        threshold;
        errors;
        movwinsize;
        cutoff_freq;
        denoisingmethod;
        lowcutoff;
        highcutoff;
        steepness;
        attenuation;
    end
    
    methods (Access = private)
        
        function loadfile(app)
            % find ID and session in name
            app.fileinfo = regexp(string(app.selectedfile), app.namepattern, 'names');
            
            % find stimulus order paradigm
            if ~isfield(app.fileinfo, 'paradigm')
                paradigm = num2str(app.assignations.paradigm(app.assignations.participant == str2double(app.fileinfo.ID)));
                app.stimorder = categorical(app.paradigms{:, [char(app.fileinfo.session) paradigm]});
            else
                app.stimorder = categorical(app.paradigms{:, [char(app.fileinfo.session) char(app.fileinfo.paradigm)]});
            end
            app.stimorder(isundefined(app.stimorder))=[];
            
            % convert data to matlab file if not already done and load it
            app.filepath = [app.folderpath '/' app.selectedfile];
            mat_filename = strrep(app.filepath, 'acq', 'mat');
            if ~exist(mat_filename, 'file')
                system(sprintf('acq2mat "%s" "%s"', app.filepath, mat_filename));
            end
            load(mat_filename, 'channels', 'samples_per_second');
            
            % format data into a table
            num_chans = length(channels);
            app.data = table();
            channames = cell(1,num_chans);
            for i=1:num_chans
                app.data{:,i} = channels{1, i}.data';
                channames{i} = regexprep(channels{1, i}.name, '[^a-zA-Z]', '_');
                if any(contains(channames(1:i-1), channames{i}))
                    channames{i} = [char(channames{i}) '_'];
                end
                app.data.Properties.VariableNames{i} = channames{i};
            end
            
            % Add timestamps
            app.fs = samples_per_second;
            timestamps = (0:1/app.fs:height(app.data)/app.fs-1/app.fs)';
            app.data.Timestamps = timestamps;
        end
        
        function [sqrt_resp, cue_before, cue_after] = cue_response(app, chan)
            cue_times = find([0; diff(app.data.CUE) > 0]);
            
            % We consider surnumerous cues can only happen before the experiment starts,
            % so remove the first(s) one(s) to match cue order form paradigms file
            while length(cue_times) > length(app.stimorder)
                cue_times(1) = [];
            end
            
            n_cues = length(cue_times);
            
            base_start = app.BaselineStartEditField.Value;
            base_end = app.BaselineEndEditField.Value;
            resp_start = app.ResponseStartEditField.Value;
            resp_end = app.ResponseEndEditField.Value;
            
            cue_before = zeros((base_start - base_end) * app.fs, n_cues);
            cue_after = zeros((resp_end - resp_start) * app.fs, n_cues);
            
            for i=1:n_cues
                cue_before(:,i) = app.data{cue_times(i) - base_start * app.fs:cue_times(i) - base_end * app.fs - 1, chan};
                cue_after(:,i) = app.data{cue_times(i) + resp_start * app.fs:cue_times(i) + resp_end * app.fs - 1, chan};
            end
            
            mean_cue_base = mean(cue_before)';
            max_cue_response = max(cue_after)';
            
            cond_resp = (max_cue_response-mean_cue_base);
            sqrt_resp = sqrt(abs(cond_resp)).*(cond_resp./abs(cond_resp));
        end
        
        function cleansignal(app, toclean, dispfig)
            if strcmp(app.denoisingmethod, 'PCA')
                % matrix cntaining relevant data
                datatoclean = app.data{:, toclean};
                
                % normalize data
                for i=1:size(datatoclean,2)
                    datatoclean(:,i) = datatoclean(:,i)./max(datatoclean(:,i));
                end
                [~, score] = pca(datatoclean);
                
                happy = false;
                
                while ~happy
                    meanpc1 = mean(score(:,1));
                    meanpc2 = mean(score(:,2));
                    stdpc1 = app.threshold * std(score(:,1));
                    stdpc2 = app.threshold * std(score(:,2));
                    
                    noise = score(:,1) > meanpc1 + stdpc1 | score(:,1) < meanpc1 - stdpc1 |...
                        score(:,2) > meanpc2 + stdpc2 | score(:,2) < meanpc2 - stdpc2;
                    
                    noise_start = find(diff(noise)>0);
                    noise_end = find(diff(noise)<0);
                    
                    if noise_start & noise_end
                        if noise_end(1) < noise_start(1)
                            noise_end(1) = [];
                        end
                    end
                    
                    noiseevents = [noise_start noise_end];
                    
                    nanoise = ones(length(noise),1);
                    
                    for i=1:size(noiseevents, 1)
                        if noiseevents(i,1)>1000 && noiseevents(i,2)+1000<length(nanoise)
                            nanoise(noiseevents(i,1)-1000:noiseevents(i,2)+1000) = NaN;
                        end
                    end
                    
                    figure; hold on
                    scatter(score(:,1), score(:,2))
                    errorbar(meanpc1, meanpc2, -stdpc2, stdpc2, -stdpc1, stdpc1)
                    xlabel('PC1')
                    ylabel('PC2')
                    
                    if dispfig
                        figure;
                        for i=1:length(toclean)
                            ax(i) = subplot(length(toclean),1,i);hold on
                            plot(app.data.Timestamps, app.data{:,toclean{i}}, 'r')
                            plot(app.data.Timestamps, app.data{:,toclean{i}} .* nanoise, 'b')
                        end
                        linkaxes(ax, 'xy')
                    end
                    
                    askhappy = uiconfirm(app.AcqNalysisUIFigure, 'Are you satisfied with the denoising?', 'Cleaning OK?',...
                        'Options', {'Yes', 'No'});
                    if strcmp(askhappy, 'Yes')
                        happy = true;
                    else
                        app.threshold = str2double(inputdlg('Enter a new threshold value', 'New threshold'));
                    end
                end
                app.data{:, toclean} = app.data{:, toclean} .* nanoise;
                
            elseif strcmp(app.denoisingmethod, 'Wavelets')
                for i=1:length(toclean)
                    [wt,f] = cwt(app.data{:, toclean{i}}, 1000, 'FrequencyLimits', [0.1 150], 'VoicesPerOctave', 4);
                    zs = zscore(abs(wt));
                    
                    low_freq = movmean(mean(zs(f<=app.cutoff_freq, :), 1), app.movwinsize);
                    high_freq = movmean(mean(zs(f>app.cutoff_freq, :), 1), app.movwinsize);
                    
                    noise = low_freq>high_freq;
                    tnoisy = app.data.Timestamps; tnoisy(~noise) = NaN;
                    tclean = app.data.Timestamps; tclean(noise) = NaN;
                    noisy = app.data{:, toclean{i}}'; noisy(~noise) = NaN;
                    clean = app.data{:, toclean{i}}'; clean(noise) = NaN;
                    
                    if dispfig
                        figure;
                        
                        ax(1)=subplot(2,1,1); hold on
                        plot(app.data.Timestamps, app.data{:, toclean{i}}, 'k')
                        plot(app.data.Timestamps, low_freq)
                        plot(app.data.Timestamps, high_freq)
                        
                        ax(2)=subplot(2,1,2); hold on
                        plot(tclean, clean, 'k')
                        plot(tnoisy, noisy, 'r')
                        
                        linkaxes(ax, 'x')
                    end
                    
                    app.data{:, toclean{i}} = clean';
                    
                end
            end
        end
        
        
        function filter(app, tofilter)
            for i=1:length(tofilter)
                data_tofilter = app.data{:, tofilter{i}};
                data_nan = isnan(data_tofilter);
                data_tofilter(data_nan) = 0;
                bp_filter = bandpass(data_tofilter, [app.lowcutoff app.highcutoff], app.fs,...
                    'Steepness', app.steepness, 'StopbandAttenuation', app.attenuation);
                bp_filter(data_nan) = NaN;
                app.data{:, tofilter{i}} = movmean(rms(bp_filter, 2), 250);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning off
            
            app.denoisingmethod = app.DenoisingButtonGroup.SelectedObject.Text;
            
            app.threshold = app.ThreshEditField.Value;
            
            app.movwinsize = app.WinsizeEditField.Value;
            app.cutoff_freq = app.CutoffEditField.Value;
            
            app.lowcutoff = app.LowfreqHzEditField.Value;
            app.highcutoff = app.HighfreqHzEditField.Value;
            app.steepness = app.SteepnessEditField.Value;
            app.attenuation = app.AttenuationdBEditField.Value;
            
            % load config file
            if exist('config.m', 'file')
                config
                
                try
                    app.folderpath = folder;
                    app.FolderLabel.Value = folder;
                    app.filesindir = dir([app.folderpath '\*.acq']);
                    app.FilesList.Items = {app.filesindir.name};
                catch
                    uialert(app.AcqNalysisUIFigure, 'Error opening folder', 'Setup error')
                end
                
                app.namepattern = namepattern;
                app.PatternEditField.Value = namepattern;
                
                if ~isempty(groups)
                    try
                        app.AssignationFileLabel.Value = groups;
                        app.assignations = readtable(groups);
                        if strfind(app.assignations.Properties.VariableNames{1}, 'x___')
                            app.assignations.Properties.VariableNames{1} = strrep(app.assignations.Properties.VariableNames{1}, 'x___', '');
                        end
                    catch
                        uialert(app.AcqNalysisUIFigure, 'Error opening assignation file', 'Setup error')
                    end
                end
                
                try
                    app.paradigms = readtable(paradigms);
                    app.ParadigmsFilesLabel.Value = paradigms;
                    if strfind(app.paradigms.Properties.VariableNames{1}, 'x___')
                        app.paradigms.Properties.VariableNames{1} = strrep(app.paradigms.Properties.VariableNames{1}, 'x___', '');
                    end
                catch
                    uialert(app.AcqNalysisUIFigure, 'Error opening paradigms file', 'Setup error')
                end
            end
        end

        % Button pushed function: DataFolderButton
        function DataFolderButtonPushed(app, event)
            app.folderpath = uigetdir('*.acq', 'Select folder');
            app.filesindir = dir([app.folderpath '\*.acq']);
            app.FilesList.Items = {app.filesindir.name};
            app.FolderLabel.Value = app.folderpath;
            
            % Make sure that focus is not lost
            app.AcqNalysisUIFigure.Visible = 'off';
            app.AcqNalysisUIFigure.Visible = 'on';
        end

        % Button pushed function: GroupAssignationButton
        function GroupAssignationButtonPushed(app, event)
            [groups_filename, groups_path] = uigetfile('*.csv', 'Select assignation file');
            groups_filepath = [groups_path groups_filename];
            app.assignations = readtable(groups_filepath);
            if strfind(app.assignations.Properties.VariableNames{1}, 'x___')
                app.assignations.Properties.VariableNames{1} = strrep(app.assignations.Properties.VariableNames{1}, 'x___', '');
            end
            app.AssignationFileLabel.Value = groups_filepath;
            
            % Make sure that focus is not lost
            app.AcqNalysisUIFigure.Visible = 'off';
            app.AcqNalysisUIFigure.Visible = 'on';
        end

        % Button pushed function: ParadigmsButton
        function ParadigmsButtonPushed(app, event)
            [paradigms_filename, paradigms_path] = uigetfile('*.csv', 'Select paradigms file');
            paradigms_filepath = [paradigms_path paradigms_filename];
            app.paradigms = readtable(paradigms_filepath);
            if strfind(app.paradigms.Properties.VariableNames{1}, 'x___')
                app.paradigms.Properties.VariableNames{1} = strrep(app.paradigms.Properties.VariableNames{1}, 'x___', '');
            end
            app.ParadigmsFilesLabel.Value = paradigms_filepath;
            
            % Make sure that focus is not lost
            app.AcqNalysisUIFigure.Visible = 'off';
            app.AcqNalysisUIFigure.Visible = 'on';
        end

        % Button pushed function: ShowAssignationsButton
        function ShowAssignationsButtonPushed(app, event)
            if ~isempty(app.assignations)
                fassi = uifigure;
                tassi = uitable(fassi);
                tassi.Position = [0 0 fassi.Position(3:4)];
                tassi.Data = app.assignations;
            end
        end

        % Button pushed function: ShowParadigmsButton
        function ShowParadigmsButtonPushed(app, event)
            if ~isempty(app.paradigms)
                fcond = uifigure;
                tcond = uitable(fcond);
                tcond.Position = [0 0 fcond.Position(3:4)];
                tcond.Data = app.paradigms;
            end
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
            app.DisplayButton.Enable = 0;
            app.RunallButton.Enable = 0;
            app.RunselectedButton.Enable = 0;
            pause(0.01)
            
            try
                chanselec = app.ChannelsUITable.Data;
                schans = chanselec.Channels(chanselec.Selected);
                if any(chanselec.Denoise)
                    if strcmp(app.denoisingmethod, 'PCA') && sum(chanselec.Denoise) < 2 % wont work with less than 2 variables
                        uialert(app.AcqNalysisUIFigure, 'Please select at least two chanels to clean data', ...
                            'Can not clean...', 'Icon', 'warning');
                        return
                    end
                    cleansignal(app, chanselec.Channels(chanselec.Denoise), 1)
                end
            catch
            end
            
            if any(chanselec.Filter)
                filter(app, chanselec.Channels(chanselec.Filter))
            end
            
            for i=1:length(schans)
                [~, cue_before, cue_after] = cue_response(app, schans{i});
                figure; hold on
                base_start = app.BaselineStartEditField.Value;
                base_end = app.BaselineEndEditField.Value;
                resp_start = app.ResponseStartEditField.Value;
                resp_end = app.ResponseEndEditField.Value;
                categ = categories(app.stimorder);
                col = get(gca, 'ColorOrder');
                for j=1:length(categ)
                    tmpb = cue_before(:,app.stimorder == categ(j));
                    tmpa = cue_after(:,app.stimorder == categ(j));
                    p{j} = plot(- base_start * app.fs:- base_end * app.fs-1, (tmpb-mean(tmpb))./mean(tmpb), 'Color', col(j,:)); %(tmpb-mean(tmpb))./mean(tmpb)
                    plot(resp_start * app.fs:resp_end * app.fs - 1, (tmpa-mean(tmpb))./mean(tmpb), 'Color', col(j,:)); %(tmpa-mean(tmpb))./mean(tmpb)
                end
                line([0 0], ylim, 'color', 'k')
                title(schans{i}, 'Interpreter', 'none')
                tmp = '[p{1}(1)';
                for i=2:length(categ)
                    tmp = [tmp ', p{' num2str(i) '}(1)'];
                end
                tmp = [tmp ']'];
                legend(eval(tmp), categ)
            end
            
            for i=1:length(schans)
                [~, cue_before, cue_after] = cue_response(app, schans{i});
                figure
                base = app.BaselineStartEditField.Value;
                resp = app.ResponseStartEditField.Value;
                categ = categories(app.stimorder);
                col = get(gca, 'ColorOrder');
                for j=1:length(categ)
                    ax(j)=subplot(length(categ),1,j);
                    hold on
                    tmpb = cue_before(:,app.stimorder == categ(j));
                    tmpa = cue_after(:,app.stimorder == categ(j));
                    plot(- base_start * app.fs:- base_end * app.fs-1, (tmpb-mean(tmpb))./mean(tmpb), 'Color', col(j,:)) %(tmpb-mean(tmpb))./mean(tmpb)
                    plot(resp_start * app.fs:resp_end * app.fs - 1, (tmpa-mean(tmpb))./mean(tmpb), 'Color', col(j,:)) %(tmpa-mean(tmpb))./mean(tmpb)
                    plot(- base_start * app.fs:- base_end * app.fs-1, mean((tmpb-mean(tmpb))./mean(tmpb), 2), 'Color', 'k', 'LineWidth', 2)
                    plot(resp_start * app.fs:resp_end * app.fs - 1, mean((tmpa-mean(tmpb))./mean(tmpb), 2), 'Color', 'k', 'LineWidth', 2)
                    line([0 0], ylim, 'color', 'k')
                    title([char(schans{i}) ' - ' char(categ(j))], 'Interpreter', 'none')
                end
                linkaxes(ax,'xy')
            end
            
            app.DisplayButton.Enable = 1;
            app.RunallButton.Enable = 1;
            app.RunselectedButton.Enable = 1;
        end

        % Value changed function: DispChanListBox
        function DispChanListBoxValueChanged(app, event)
            value = app.DispChanListBox.Value;
            if verLessThan('MATLAB','9.9')
                app.UIAxes.cla;
            else
                cla(app.UIAxes);                
            end
            hold(app.UIAxes, 'off');
            for i=1:length(value)
                plot(app.UIAxes, app.data.Timestamps(1:5:end), app.data{1:5:end, value{i}}./(2*max(app.data{:, value{i}}))+i-0.5, 'DisplayName', value{i})
                if i==1
                    hold(app.UIAxes, 'on')
                end
            end
            legend(app.UIAxes)
            axis(app.UIAxes, 'auto')
        end

        % Value changed function: ZoomButton
        function ZoomButtonValueChanged(app, event)
            app.MoveButton.Value = 0;
            value = app.ZoomButton.Value;
            if value
                zoom(app.UIAxes, 'on')
            else
                zoom(app.UIAxes, 'off')
            end
        end

        % Value changed function: MoveButton
        function MoveButtonValueChanged(app, event)
            app.ZoomButton.Value = 0;
            value = app.MoveButton.Value;
            if value
                pan(app.UIAxes, 'on')
            else
                pan(app.UIAxes, 'off')
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            axis(app.UIAxes, 'auto')
        end

        % Button pushed function: CloseallButton
        function CloseallButtonPushed(app, event)
            close all
        end

        % Value changed function: FilesList
        function FilesListValueChanged(app, event)
            try
                app.selectedfile = app.FilesList.Value{1};
                loadfile(app)
                
                varnames = app.data.Properties.VariableNames;
                
                % update variables in table if necessary and display listbox
                if isempty(app.ChannelsUITable.Data)
                    chantable = table();
                    chantable.Channels = varnames';
                    chantable.Selected = false(length(varnames), 1);
                    chantable.Denoise = false(length(varnames), 1);
                    chantable.Filter = false(length(varnames), 1);
                    
                    app.ChannelsUITable.Data = chantable;
                    app.ChannelsUITable.ColumnEditable = true;
                    app.ChannelsUITable.ColumnName = chantable.Properties.VariableNames;
                end
                
                if ~isequal(app.ChannelsUITable.Data.Channels, varnames')
                    if length(app.ChannelsUITable.Data.Channels) == length(varnames')
                        app.ChannelsUITable.Data.Channels = varnames';
                    else
                        chantable = table();
                        chantable.Channels = varnames';
                        chantable.Selected = false(length(varnames), 1);
                        chantable.Denoise = false(length(varnames), 1);
                        chantable.Filter = false(length(varnames), 1);
                        
                        app.ChannelsUITable.Data = chantable;
                        app.ChannelsUITable.ColumnEditable = true;
                        app.ChannelsUITable.ColumnName = chantable.Properties.VariableNames;
                    end
                end
                
                app.ReferenceDropDown.Items = varnames;
                if any(contains(varnames, 'CUE'))
                    app.ReferenceDropDown.Value = 'CUE';
                end
                
                app.DispChanListBox.Items = varnames;
                
            catch ME
                getReport(ME)
                if strcmp(ME.identifier, 'MATLAB:nonExistentField') | strcmp(ME.identifier, 'MATLAB:minrhs')
                    uialert(app.AcqNalysisUIFigure, 'Error: check pattern', 'Error loading data')
                elseif strcmp(ME.identifier, 'MATLAB:REGEXP:invalidInputs')
                    uialert(app.AcqNalysisUIFigure, 'Error: check files', 'Error loading data')
                else
                    uialert(app.AcqNalysisUIFigure, 'Error: unknown error', 'Error loading data')
                end
            end
        end

        % Value changed function: PatternEditField
        function PatternEditFieldValueChanged(app, event)
            app.namepattern = app.PatternEditField.Value;
        end

        % Button pushed function: RunallButton
        function RunallButtonPushed(app, event)
            try
                chanselec = app.ChannelsUITable.Data;
                schans = chanselec.Channels(chanselec.Selected);
                n_chans = length(schans);
                
                if n_chans == 0
                    uialert(app.AcqNalysisUIFigure, 'Please select a channel', ...
                        'No channel selected', 'Icon', 'warning')
                    return
                end
                
                if any(chanselec.Denoise)
                    if strcmp(app.denoisingmethod, 'PCA') && sum(chanselec.Denoise) < 2  % wont work with less than 2 variables
                        uialert(app.AcqNalysisUIFigure, 'Please select at least two chanels to clean data', ...
                            'Can not clean...', 'Icon', 'warning');
                        return
                    end
                end
                
                results_table = table();
                results_table.Properties.DimensionNames{1}='Participant';
                
                n_files = length(app.FilesList.Items);
                
                % Create array to store errors
                app.errors = {};
                
                wb = waitbar(0,' ','Name','Running...',...
                    'CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
                wb.Children(2).Title.Interpreter = 'none';
                setappdata(wb,'canceling',0);
                
                for file=1:n_files
                    if getappdata(wb,'canceling')
                        delete(wb)
                        break
                    end
                    
                    try
                        % Select and load next file
                        app.selectedfile = app.FilesList.Items{file};
                        waitbar(file/n_files,wb,sprintf('Loading %s',app.selectedfile))
                        loadfile(app)
                        
                        if ~isnan(str2double(app.fileinfo.ID))
                            ID = sprintf('%.3d', str2double(app.fileinfo.ID));
                        else % can probably be improved
                            tmp = regexp(app.fileinfo.ID,{'\d+', '[a-zA-Z]'} , 'match');
                            if ~isempty(tmp{1})
                                ID = sprintf('%.3d%s', str2double(tmp{1}), char(tmp{2}));
                            end
                        end
                        
                        % Denoise if necessary
                        if any(chanselec.Denoise)
                            waitbar(file/n_files,wb,sprintf('Denoising %s',ID))
                            cleansignal(app, chanselec.Channels(chanselec.Denoise), 0)
                        end
                        
                        % Filter if necessary
                        if any(chanselec.Filter)
                            waitbar(file/n_files,wb,sprintf('Filtering %s',ID))
                            filter(app, chanselec.Channels(chanselec.Filter))
                        end
                        
                        % Calculate score
                        waitbar(file/n_files,wb,sprintf('Calculating scores %s',ID))
                        
                        for ch=1:n_chans
                            chan = schans{ch};
                            
                            % Create variable names from categories
                            vars = cellstr(app.stimorder);
                            categs = categories(app.stimorder);
                            for ca=1:length(categs)
                                categ = categs{ca};
                                tmp = find(strcmp(vars, categ));
                                n=1;
                                for stim = tmp'
                                    vars{stim} = sprintf('%s_%s_%s_%02d',...
                                        chan,...
                                        app.fileinfo.session,...
                                        categ,...
                                        n);
                                    n= n+1;
                                end
                            end
                            
                            % Remove illegal characters
                            if any(contains(vars, '+'))
                                vars = cellfun(@(x) strrep(x, '+', 'p'), vars,...
                                    'UniformOutput', false);
                            end
                            if any(contains(vars, '-'))
                                vars = cellfun(@(x) strrep(x, '-', 'm'), vars,...
                                    'UniformOutput', false);
                            end
                            
                            results_table{ID, vars} = cue_response(app, chan)';
                        end
                        
                    catch ME
                        getReport(ME)
                        disp(ME.stack)
                        app.errors{end+1,1} = sprintf('%s: %s', app.FilesList.Items{file}, ME.message);
                    end
                end
                
                results_table = sortrows(results_table, 'RowNames');
                results_table = results_table(:, sort(results_table.Properties.VariableNames));
                
                ext = {'*.xlsx', 'Excel File'; '*.csv', 'CSV'};
                [file, path] = uiputfile(ext, 'Where do you wish to save the data?');
                writetable(results_table, [path file], 'WriteRowNames', 1)
                
                % Make sure that focus is not lost
                app.AcqNalysisUIFigure.Visible = 'off';
                app.AcqNalysisUIFigure.Visible = 'on';
                
                delete(wb)
                
                if ~isempty(app.errors)
                    uialert(app.AcqNalysisUIFigure, app.errors, 'Errors...')
                end
                
            catch ME
                getReport(ME)
                uialert(app.AcqNalysisUIFigure, 'error', 'Error...')
                if isgraphics(wb)
                    delete(wb)
                end
            end
        end

        % Value changed function: ThreshEditField
        function ThreshEditFieldValueChanged(app, event)
            app.threshold = app.ThreshEditField.Value;
        end

        % Value changed function: WinsizeEditField
        function WinsizeEditFieldValueChanged(app, event)
            app.movwinsize = app.WinsizeEditField.Value;
        end

        % Value changed function: CutoffEditField
        function CutoffEditFieldValueChanged(app, event)
            app.cutoff_freq = app.CutoffEditField.Value;
        end

        % Selection changed function: DenoisingButtonGroup
        function DenoisingButtonGroupSelectionChanged(app, event)
            app.denoisingmethod = app.DenoisingButtonGroup.SelectedObject.Text;
            
        end

        % Button pushed function: RunselectedButton
        function RunselectedButtonPushed(app, event)
            try
                chanselec = app.ChannelsUITable.Data;
                schans = chanselec.Channels(chanselec.Selected);
                n_chans = length(schans);
                
                if n_chans == 0
                    uialert(app.AcqNalysisUIFigure, 'Please select a channel', ...
                        'No channel selected', 'Icon', 'warning')
                    return
                end
                
                if any(chanselec.Denoise)
                    if strcmp(app.denoisingmethod, 'PCA') && sum(chanselec.Denoise) < 2  % wont work with less than 2 variables
                        uialert(app.AcqNalysisUIFigure, 'Please select at least two chanels to clean data', ...
                            'Can not clean...', 'Icon', 'warning');
                        return
                    end
                end
                
                results_table = table();
                results_table.Properties.DimensionNames{1}='Participant';
                
                % Create array to store errors
                app.errors = {};
                
                wb = waitbar(0,' ','Name','Running...',...
                    'CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
                wb.Children(2).Title.Interpreter = 'none';
                setappdata(wb,'canceling',0);
                
                n_files = length(app.FilesList.Value);
                
                for f=1:n_files
                    if getappdata(wb,'canceling')
                        delete(wb)
                        break
                    end
                    
                    try
                        app.selectedfile = app.FilesList.Value{f};
                        waitbar(f/n_files,wb,sprintf('Loading %s',app.selectedfile))
                        loadfile(app)
                        
                        if ~isnan(str2double(app.fileinfo.ID))
                            ID = sprintf('%.3d', str2double(app.fileinfo.ID));
                        else % can probably be improved
                            tmp = regexp(app.fileinfo.ID,{'\d+', '[a-zA-Z]'} , 'match');
                            if ~isempty(tmp{1})
                                ID = sprintf('%.3d%s', str2double(tmp{1}), char(tmp{2}));
                            end
                        end
                        
                        % Denoise if necessary
                        if any(chanselec.Denoise)
                            waitbar(fifle/n_files,wb,sprintf('Denoising %s',ID))
                            cleansignal(app, chanselec.Channels(chanselec.Denoise), 0)
                        end
                        
                        % Filter if necessary
                        if any(chanselec.Filter)
                            waitbar(f/n_files,wb,sprintf('Filtering %s',ID))
                            filter(app, chanselec.Channels(chanselec.Filter))
                        end
                        
                        % Calculate score
                        waitbar(f/n_files,wb,sprintf('Calculating scores %s',ID))
                        
                        for ch=1:n_chans
                            chan = schans{ch};
                            
                            % Create variable names from categories
                            vars = cellstr(app.stimorder);
                            categs = categories(app.stimorder);
                            for ca=1:length(categs)
                                categ = categs{ca};
                                tmp = find(strcmp(vars, categ));
                                n=1;
                                for stim = tmp'
                                    vars{stim} = sprintf('%s_%s_%s_%d',...
                                        chan,...
                                        app.fileinfo.session,...
                                        categ,...
                                        n);
                                    n= n+1;
                                end
                            end
                            
                            % Remove illegal characters
                            if any(contains(vars, '+'))
                                vars = cellfun(@(x) strrep(x, '+', 'p'), vars,...
                                    'UniformOutput', false);
                            end
                            if any(contains(vars, '-'))
                                vars = cellfun(@(x) strrep(x, '-', 'm'), vars,...
                                    'UniformOutput', false);
                            end
                            results_table{ID, vars} = cue_response(app, chan)';
                        end
                    catch ME
                        getReport(ME)
                        %                         disp(ME.stack)
                        app.errors{end+1,1} = sprintf('%s: %s', app.FilesList.Items{f}, ME.message);
                    end
                end
                
                results_table = sortrows(results_table, 'RowNames');
                results_table = results_table(:, sort(results_table.Properties.VariableNames));
                
                ext = {'*.xlsx', 'Excel File'; '*.csv', 'CSV'};
                [file, path] = uiputfile(ext, 'Where do you wish to save the data?');
                writetable(results_table, [path file], 'WriteRowNames', 1)
                
                % Make sure that focus is not lost
                app.AcqNalysisUIFigure.Visible = 'off';
                app.AcqNalysisUIFigure.Visible = 'on';
                
                delete(wb)
                
                if ~isempty(app.errors)
                    uialert(app.AcqNalysisUIFigure, app.errors, 'Errors...')
                end
            catch ME
                getReport(ME)
                uialert(app.AcqNalysisUIFigure, ME.message, 'Errors...')
                if isgraphics(wb)
                    delete(wb)
                end
            end
        end

        % Value changed function: LowfreqHzEditField
        function LowfreqHzEditFieldValueChanged(app, event)
            app.lowcutoff = app.LowfreqHzEditField.Value;
        end

        % Value changed function: HighfreqHzEditField
        function HighfreqHzEditFieldValueChanged(app, event)
            app.highcutoff = app.HighfreqHzEditField.Value;
        end

        % Value changed function: SteepnessEditField
        function SteepnessEditFieldValueChanged(app, event)
            app.steepness = app.SteepnessEditField.Value;
        end

        % Value changed function: AttenuationdBEditField
        function AttenuationdBEditFieldValueChanged(app, event)
            app.attenuation = app.AttenuationdBEditField.Value;
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            try
                system('HelpMe.pdf')
            catch
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AcqNalysisUIFigure = uifigure('Visible', 'off');
            app.AcqNalysisUIFigure.Name = 'AcqNalysis';
            app.TabGroup = uitabgroup(app.AcqNalysisUIFigure);
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.DisplayButton = uibutton(app.AnalysisTab, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
            app.RunallButton = uibutton(app.AnalysisTab, 'push');
            app.RunallButton.ButtonPushedFcn = createCallbackFcn(app, @RunallButtonPushed, true);
            app.RunallButton.Text = 'Run all';
            app.ChannelsUITable = uitable(app.AnalysisTab);
            app.ChannelsUITable.ColumnName = '';
            app.ChannelsUITable.RowName = {};
            app.CloseallButton = uibutton(app.AnalysisTab, 'push');
            app.CloseallButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallButtonPushed, true);
            app.CloseallButton.Text = 'Close all';
            app.RunselectedButton = uibutton(app.AnalysisTab, 'push');
            app.RunselectedButton.ButtonPushedFcn = createCallbackFcn(app, @RunselectedButtonPushed, true);
            app.RunselectedButton.Text = 'Run selected';
            app.PeristimulusresponsePanel = uipanel(app.AnalysisTab);
            app.PeristimulusresponsePanel.Title = 'Peri-stimulus response';
            app.BaselineIntervalsEditFieldLabel = uilabel(app.PeristimulusresponsePanel);
            app.BaselineIntervalsEditFieldLabel.Text = 'Baseline Interval (s)';
            app.BaselineStartEditField = uieditfield(app.PeristimulusresponsePanel, 'numeric');
            app.BaselineStartEditField.Value = 2;
            app.ReferenceDropDownLabel = uilabel(app.PeristimulusresponsePanel);
            app.ReferenceDropDownLabel.Text = 'Reference';
            app.ReferenceDropDown = uidropdown(app.PeristimulusresponsePanel);
            app.ReferenceDropDown.Items = {};
            app.ReferenceDropDown.Value = {};
            app.ResponseIntervalsEditFieldLabel = uilabel(app.PeristimulusresponsePanel);
            app.ResponseIntervalsEditFieldLabel.Text = 'Response Interval (s)';
            app.ResponseStartEditField = uieditfield(app.PeristimulusresponsePanel, 'numeric');
            app.BaselineEndEditField = uieditfield(app.PeristimulusresponsePanel, 'numeric');
            app.ResponseEndEditField = uieditfield(app.PeristimulusresponsePanel, 'numeric');
            app.ResponseEndEditField.Value = 6;
            app.Panel = uipanel(app.AnalysisTab);
            app.PCALabel = uilabel(app.Panel);
            app.PCALabel.Text = 'PCA:';
            app.WaveletsLabel = uilabel(app.Panel);
            app.WaveletsLabel.Text = 'Wavelets:';
            app.ThreshEditFieldLabel = uilabel(app.Panel);
            app.ThreshEditFieldLabel.Text = 'Thresh';
            app.ThreshEditField = uieditfield(app.Panel, 'numeric');
            app.ThreshEditField.ValueChangedFcn = createCallbackFcn(app, @ThreshEditFieldValueChanged, true);
            app.ThreshEditField.Value = 30;
            app.WinsizeEditFieldLabel = uilabel(app.Panel);
            app.WinsizeEditFieldLabel.Text = 'Win size';
            app.WinsizeEditField = uieditfield(app.Panel, 'numeric');
            app.WinsizeEditField.ValueChangedFcn = createCallbackFcn(app, @WinsizeEditFieldValueChanged, true);
            app.WinsizeEditField.Value = 150;
            app.CutoffEditFieldLabel = uilabel(app.Panel);
            app.CutoffEditFieldLabel.Text = 'Cutoff';
            app.CutoffEditField = uieditfield(app.Panel, 'numeric');
            app.CutoffEditField.ValueChangedFcn = createCallbackFcn(app, @CutoffEditFieldValueChanged, true);
            app.CutoffEditField.Value = 50;
            app.BandpassfilterPanel = uipanel(app.AnalysisTab);
            app.BandpassfilterPanel.Title = 'Bandpass filter';
            app.SteepnessEditFieldLabel = uilabel(app.BandpassfilterPanel);
            app.SteepnessEditFieldLabel.Text = 'Steepness';
            app.SteepnessEditField = uieditfield(app.BandpassfilterPanel, 'numeric');
            app.SteepnessEditField.ValueChangedFcn = createCallbackFcn(app, @SteepnessEditFieldValueChanged, true);
            app.SteepnessEditField.Value = 0.85;
            app.AttenuationdBEditFieldLabel = uilabel(app.BandpassfilterPanel);
            app.AttenuationdBEditFieldLabel.Text = 'Attenuation (dB)';
            app.AttenuationdBEditField = uieditfield(app.BandpassfilterPanel, 'numeric');
            app.AttenuationdBEditField.ValueChangedFcn = createCallbackFcn(app, @AttenuationdBEditFieldValueChanged, true);
            app.AttenuationdBEditField.Value = 61;
            app.LowfreqHzEditFieldLabel = uilabel(app.BandpassfilterPanel);
            app.LowfreqHzEditFieldLabel.Text = 'Low freq (Hz)';
            app.LowfreqHzEditField = uieditfield(app.BandpassfilterPanel, 'numeric');
            app.LowfreqHzEditField.ValueChangedFcn = createCallbackFcn(app, @LowfreqHzEditFieldValueChanged, true);
            app.LowfreqHzEditField.Value = 25;
            app.HighfreqHzEditFieldLabel = uilabel(app.BandpassfilterPanel);
            app.HighfreqHzEditFieldLabel.Text = 'High freq (Hz)';
            app.HighfreqHzEditField = uieditfield(app.BandpassfilterPanel, 'numeric');
            app.HighfreqHzEditField.ValueChangedFcn = createCallbackFcn(app, @HighfreqHzEditFieldValueChanged, true);
            app.HighfreqHzEditField.Value = 500;
            app.DenoisingButtonGroup = uibuttongroup(app.AnalysisTab);
            app.DenoisingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DenoisingButtonGroupSelectionChanged, true);
            app.DenoisingButtonGroup.Title = 'Denoising ';
            app.PCAButton = uiradiobutton(app.DenoisingButtonGroup);
            app.PCAButton.Text = 'PCA';
            app.PCAButton.Value = true;
            app.WaveletsButton = uiradiobutton(app.DenoisingButtonGroup);
            app.WaveletsButton.Text = 'Wavelets';
            app.DisplaydataTab = uitab(app.TabGroup);
            app.DisplaydataTab.Title = 'Display data';
            app.UIAxes = uiaxes(app.DisplaydataTab);
            xlabel(app.UIAxes, 'Time (s)')
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'[ ]'};
            app.DispChanListBox = uilistbox(app.DisplaydataTab);
            app.DispChanListBox.Items = {};
            app.DispChanListBox.Multiselect = 'on';
            app.DispChanListBox.ValueChangedFcn = createCallbackFcn(app, @DispChanListBoxValueChanged, true);
            app.DispChanListBox.Value = {};
            app.ZoomButton = uibutton(app.DisplaydataTab, 'state');
            app.ZoomButton.ValueChangedFcn = createCallbackFcn(app, @ZoomButtonValueChanged, true);
            app.ZoomButton.Text = 'Zoom';
            app.MoveButton = uibutton(app.DisplaydataTab, 'state');
            app.MoveButton.ValueChangedFcn = createCallbackFcn(app, @MoveButtonValueChanged, true);
            app.MoveButton.Text = 'Move';
            app.ResetButton = uibutton(app.DisplaydataTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.FilesPanel = uipanel(app.AcqNalysisUIFigure);
            app.FilesPanel.Title = 'Files';
            app.DataFolderButton = uibutton(app.FilesPanel, 'push');
            app.DataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @DataFolderButtonPushed, true);
            app.DataFolderButton.Text = 'Data Folder';
            app.FolderLabel = uitextarea(app.FilesPanel);
            app.AssignationFileLabel = uitextarea(app.FilesPanel);
            app.ParadigmsFilesLabel = uitextarea(app.FilesPanel);
            app.GroupAssignationButton = uibutton(app.FilesPanel, 'push');
            app.GroupAssignationButton.ButtonPushedFcn = createCallbackFcn(app, @GroupAssignationButtonPushed, true);
            app.GroupAssignationButton.Text = 'Group Assignation';
            app.ParadigmsButton = uibutton(app.FilesPanel, 'push');
            app.ParadigmsButton.ButtonPushedFcn = createCallbackFcn(app, @ParadigmsButtonPushed, true);
            app.ParadigmsButton.Text = 'Paradigms';
            app.ShowAssignationsButton = uibutton(app.FilesPanel, 'push');
            app.ShowAssignationsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAssignationsButtonPushed, true);
            app.ShowAssignationsButton.Text = 'Show';
            app.ShowParadigmsButton = uibutton(app.FilesPanel, 'push');
            app.ShowParadigmsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowParadigmsButtonPushed, true);
            app.ShowParadigmsButton.Text = 'Show';
            app.PatternLabel = uilabel(app.FilesPanel);
            app.PatternLabel.Text = 'Pattern :';
            app.PatternEditField = uieditfield(app.FilesPanel, 'text');
            app.PatternEditField.ValueChangedFcn = createCallbackFcn(app, @PatternEditFieldValueChanged, true);
            app.IDsessionparadigmLabel = uilabel(app.FilesPanel);
            app.IDsessionparadigmLabel.Text = '(?<>) - ID, session, paradigm';
            app.HelpButton = uibutton(app.FilesPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = '?';
            app.FilesList = uilistbox(app.AcqNalysisUIFigure);
            app.FilesList.Items = {};
            app.FilesList.Multiselect = 'on';
            app.FilesList.ValueChangedFcn = createCallbackFcn(app, @FilesListValueChanged, true);
            app.FilesList.Value = {};
            % Show the figure after all components are created
            app.AcqNalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AcqNalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AcqNalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AcqNalysisUIFigure)
        end
    end
end